classdef AtlasBased < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        PETDataButton                  matlab.ui.control.Button
        PETnamesTextArea               matlab.ui.control.TextArea
        GridLayout3                    matlab.ui.container.GridLayout
        OptionsLabel                   matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        SaveAdaptiveTPMCheckBox        matlab.ui.control.CheckBox
        GridLayout5                    matlab.ui.container.GridLayout
        RunButton                      matlab.ui.control.Button
        ParametersLabel                matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        BoundingBoxEditFieldLabel      matlab.ui.control.Label
        BoundingBoxEditField           matlab.ui.control.EditField
        GridLayout7                    matlab.ui.container.GridLayout
        VoxelSizesEditFieldLabel       matlab.ui.control.Label
        VoxelSizesEditField            matlab.ui.control.EditField
        GridLayout8                    matlab.ui.container.GridLayout
        RegularTermEditFieldLabel      matlab.ui.control.Label
        RegularTermEditField           matlab.ui.control.NumericEditField
        GridLayout9                    matlab.ui.container.GridLayout
        NormalisePrefixEditFieldLabel  matlab.ui.control.Label
        NormalisePrefixEditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PETDataButton
        function PETDataButtonPushed(app, event)
           PETnames = spm_select(Inf,'image','Select PET images');
           if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
           end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            PETnames = app.PETnamesTextArea.Value;
            bbox = str2num(app.BoundingBoxEditField.Value);
            voxelsize = str2num(app.VoxelSizesEditField.Value);
            [bx,by] = size(bbox);
            [vx,vy] = size(voxelsize);
            normprefix = app.NormalisePrefixEditField.Value;
            regularterm = app.RegularTermEditField.Value;
            saveTPM = app.SaveAdaptiveTPMCheckBox.Value;
            if isempty(PETnames{1,1})
                uialert(app.UIFigure,'PET images cannot be empty.','Alert');
            elseif  bx ~=2 || by ~=3
                uialert(app.UIFigure,'The size of bounding box should be 2*3.','Alert');   
            elseif vx ~=1 || vy ~= 3
                uialert(app.UIFigure,'The size of voxel size should be 1*3.','Alert');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Adaptive TPM based Spatial Normalization is in progress');
                AtlasBasedSpatialNormalizationMethod(PETnames,bbox,voxelsize,normprefix,regularterm,d,saveTPM);
                close(d);
                fprintf('Down: Atlas Based Spatial Normalization.\n');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETDataButton = uibutton(app.GridLayout2, 'push');
            app.PETDataButton.ButtonPushedFcn = createCallbackFcn(app, @PETDataButtonPushed, true);
            app.PETDataButton.Text = 'PET Data';
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.OptionsLabel = uilabel(app.GridLayout3);
            app.OptionsLabel.Text = 'Options';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SaveAdaptiveTPMCheckBox = uicheckbox(app.GridLayout4);
            app.SaveAdaptiveTPMCheckBox.Text = '   Save Adaptive TPM';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.RunButton = uibutton(app.GridLayout5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ParametersLabel = uilabel(app.GridLayout5);
            app.ParametersLabel.Text = 'Parameters';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.BoundingBoxEditFieldLabel = uilabel(app.GridLayout6);
            app.BoundingBoxEditFieldLabel.Text = 'Bounding Box';
            app.BoundingBoxEditField = uieditfield(app.GridLayout6, 'text');
            app.BoundingBoxEditField.Value = '-90,-126,-72;90,90,108';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.VoxelSizesEditFieldLabel = uilabel(app.GridLayout7);
            app.VoxelSizesEditFieldLabel.Text = 'Voxel Sizes';
            app.VoxelSizesEditField = uieditfield(app.GridLayout7, 'text');
            app.VoxelSizesEditField.Value = '1.5, 1.5, 1.5';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.RegularTermEditFieldLabel = uilabel(app.GridLayout8);
            app.RegularTermEditFieldLabel.Text = 'Regular Term ';
            app.RegularTermEditField = uieditfield(app.GridLayout8, 'numeric');
            app.RegularTermEditField.Limits = [0 Inf];
            app.RegularTermEditField.Value = 15;
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.NormalisePrefixEditFieldLabel = uilabel(app.GridLayout9);
            app.NormalisePrefixEditFieldLabel.Text = 'Normalise Prefix';
            app.NormalisePrefixEditField = uieditfield(app.GridLayout9, 'text');
            app.NormalisePrefixEditField.Value = 'w';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtlasBased

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end